classdef arduino_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        GridLayout4             matlab.ui.container.GridLayout
        EnablePlotsSwitch       matlab.ui.control.Switch
        EnablePlotsSwitchLabel  matlab.ui.control.Label
        PWMSlider               matlab.ui.control.Slider
        PWMSliderLabel          matlab.ui.control.Label
        RightPanel              matlab.ui.container.Panel
        GridLayout3             matlab.ui.container.GridLayout
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Time % Time
        PWMValues
        Temps
        numTracked = 100;
        ard 
        referenceResistor = 100000.0;  % Assuming a 100kΩ resistor for voltage division
        RInfinity = 100000.0 * exp(-4540.0 / 298.15); % B=4540, R25=100k, TR=298.15
        B = 4540.0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Time = zeros(1,app.numTracked);
            app.PWMValues = zeros(1,app.numTracked);
            app.Temps = zeros(1,app.numTracked);
            app.ard = arduino('COM3','Uno');
        end

        % Value changed function: EnablePlotsSwitch
        function EnablePlotsSwitchValueChanged(app, event)
            
            while strcmp(app.EnablePlotsSwitch.Value,"On")
                app.PWMValues = [app.PWMValues(2:end),app.PWMSlider.Value];
                app.Time = [app.Time(2:end),app.Time(end)+1];

                ardInput = readVoltage(app.ard,'A0');
                resV = 5.0 - ardInput;
                curr = resV / app.referenceResistor;
                resistance = ardInput / curr;
                temperatureKelvin = app.B / log(resistance / app.RInfinity);
                temperatureCelsius = temperatureKelvin - 273.15;
                

                app.Temps = [app.Temps(2:end),temperatureCelsius];

                plot(app.UIAxes,app.Time,app.PWMValues);
                axis(app.UIAxes,[app.Time(1),app.Time(end),-1,256]);

                plot(app.UIAxes2,app.Time,app.Temps);
                axis(app.UIAxes2,[app.Time(1),app.Time(end),0,35]);

                drawnow;
            end

        end

        % Value changed function: PWMSlider
        function PWMSliderValueChanged(app, event)
            value = app.PWMSlider.Value/255;
            writePWMDutyCycle(app.ard,'D6',value);
        end

        % Value changing function: PWMSlider
        function PWMSliderValueChanging(app, event)
            changingValue = event.Value/255;
            writePWMDutyCycle(app.ard,'D6',changingValue);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            writePWMDutyCycle(app.ard,'D6',0);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.LeftPanel);
            app.PWMSliderLabel = uilabel(app.GridLayout4);
            app.PWMSliderLabel.Text = 'PWM';
            app.PWMSlider = uislider(app.GridLayout4);
            app.PWMSlider.Limits = [0 255];
            app.PWMSlider.ValueChangedFcn = createCallbackFcn(app, @PWMSliderValueChanged, true);
            app.PWMSlider.ValueChangingFcn = createCallbackFcn(app, @PWMSliderValueChanging, true);
            app.EnablePlotsSwitchLabel = uilabel(app.GridLayout4);
            app.EnablePlotsSwitchLabel.Text = 'Enable Plots';
            app.EnablePlotsSwitch = uiswitch(app.GridLayout4, 'slider');
            app.EnablePlotsSwitch.ValueChangedFcn = createCallbackFcn(app, @EnablePlotsSwitchValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'PWM')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Value')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout3);
            title(app.UIAxes2, 'Temperature')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'T (C)')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = arduino_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end